classdef MINIPROJECT_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        TextureValuesPanel           matlab.ui.container.Panel
        EnergyEditFieldLabel         matlab.ui.control.Label
        EnergyEditField              matlab.ui.control.NumericEditField
        ContrastEditFieldLabel       matlab.ui.control.Label
        ContrastEditField            matlab.ui.control.NumericEditField
        EntropyEditFieldLabel        matlab.ui.control.Label
        EntropyEditField             matlab.ui.control.NumericEditField
        HomogeneityEditFieldLabel    matlab.ui.control.Label
        HomogeneityEditField         matlab.ui.control.NumericEditField
        InsertImageButton            matlab.ui.control.Button
        BuiltinfunctionLabel         matlab.ui.control.Label
        CalculatedLabel              matlab.ui.control.Label
        EnergyEditField_2Label       matlab.ui.control.Label
        EnergyEditField_2            matlab.ui.control.NumericEditField
        ContrastEditField_2Label     matlab.ui.control.Label
        ContrastEditField_2          matlab.ui.control.NumericEditField
        HomogeneityEditField_2Label  matlab.ui.control.Label
        HomogeneityEditField_2       matlab.ui.control.NumericEditField
        CorrelationEditFieldLabel    matlab.ui.control.Label
        CorrelationEditField         matlab.ui.control.NumericEditField
        IMAGETEXTUREFEATUREEXTRACTIONUSINGGLCMAPPROACHLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InsertImageButton
        function InsertImageButtonPushed(app, event)
           global x;
           [filename pathname]=uigetfile({'*jpg'},"Open file");
           fullpathname=strcat(pathname,filename);
           x=imread(fullpathname);
           imshow(x,'parent',app.UIAxes_2);
            
            %making a vector to st
features=[]


% takinhg image as a input by the function imread()
% and showing it on output by function imread()

subplot(1,3,1)
imshow(x);
title('Original Image');

% converting Original image to grayscale image by function rgb2gray()
% and showing it in output 
gray=rgb2gray(x);
subplot(1,3,2)
imshow(gray);
title('Converted to Grayscale image');
imshow(gray,'parent',app.UIAxes_3);

% the grayscale image is used to find the gray level co-occurrence matrix
% or glcm by the function graycomatrix()
glcm=graycomatrix(gray)

stats = graycoprops(glcm,'Contrast Correlation Energy Homogeneity');
Contrast=stats.Contrast;
Correlation=stats.Correlation;
Energy=stats.Energy;
Homogeneity=stats.Homogeneity;

app.EnergyEditField_2.Value=Energy;
app.ContrastEditField_2.Value=Contrast;
app.HomogeneityEditField_2.Value=Homogeneity;
app.CorrelationEditField.Value=Correlation;


% here the glcm is converted to normalised glcm which is reuired for our
% calculation first we add transpose of glcm to its owm matrix and then 
% divide each element of the new matrix (formed by adding glcm and its 
% transpose) by the sum of elements in it.This gives us normalised glcm
% sum of element of matrix is calculated by function sum(sum()) 
A=glcm;
num_rows=size(A,1)
num_cols=size(A,2)

for i=1:num_rows
    for j=1:num_cols
        trans(i,j)=A(j,i);
    end
end


for i=1:1:num_rows
    for j=1:1:num_cols
        A(i,j)=A(i,j)+trans(i,j) ;
    end
end

factor=sum(sum(A));

for i=1:1:num_rows
    for j=1:1:num_cols
        A(i,j)=A(i,j)/factor;
    end
end

disp(A);

% Now after getting normalised glam matrix we use it for texture feature 
% calculation

%First is Energy

energy=0;
for i=1:1:num_rows
    for j=1:1:num_cols
        energy = energy + A(i,j)^2;
    end
end
disp(energy);

%Second Contrast

contrast=0;
for i=1:1:num_rows
    for j=1:1:num_cols
        contrast = contrast + A(i,j).*((i-j)^2);
    end
end
disp(contrast);

%Third Homogeneity

homogeneity=0;
for i=1:1:num_rows
    for j=1:1:num_cols
        homogeneity = homogeneity + A(i,j).*(1/(1+(i-j)^2));
    end
end
disp(homogeneity);


%Fourth Entropy
% we need another matrix for the calculation of entropy so in the below 
% for loop the first step is done.And in the second for loop the final 
% calculation is done.
for i=1:1:num_rows
    for j=1:1:num_cols
  
        B(i,j)=log(A(i,j));
    end
end

entropy=0;
for i=1:1:num_rows
    for j=1:1:num_cols
        entropy = entropy - ( A(i,j)*B(i,j) );
    end
end
disp(entropy); 

% defing the feature in a vector called features and then
% plotting the different values in graph

features=[energy,contrast,homogeneity,entropy]

x=0:0.33:1;

subplot(1,3,3)
plot(x,features,'r--s')
xlabel('X')
ylabel('Texture Features Values')
title('Plot of the energy,contrast,homogeneity,entropy')


plot(app.UIAxes,x,features);

app.EnergyEditField.Value=energy;
app.ContrastEditField.Value=contrast;
app.HomogeneityEditField.Value=homogeneity;
app.EntropyEditField.Value=entropy;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Texture Values Plotted')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Original Image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Grayscale Image')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.TextureValuesPanel = uipanel(app.UIFigure);
            app.TextureValuesPanel.Title = 'Texture Values';
            app.EnergyEditFieldLabel = uilabel(app.TextureValuesPanel);
            app.EnergyEditFieldLabel.Text = 'Energy';
            app.EnergyEditField = uieditfield(app.TextureValuesPanel, 'numeric');
            app.ContrastEditFieldLabel = uilabel(app.TextureValuesPanel);
            app.ContrastEditFieldLabel.Text = 'Contrast';
            app.ContrastEditField = uieditfield(app.TextureValuesPanel, 'numeric');
            app.EntropyEditFieldLabel = uilabel(app.TextureValuesPanel);
            app.EntropyEditFieldLabel.Text = 'Entropy';
            app.EntropyEditField = uieditfield(app.TextureValuesPanel, 'numeric');
            app.HomogeneityEditFieldLabel = uilabel(app.TextureValuesPanel);
            app.HomogeneityEditFieldLabel.Text = 'Homogeneity';
            app.HomogeneityEditField = uieditfield(app.TextureValuesPanel, 'numeric');
            app.InsertImageButton = uibutton(app.TextureValuesPanel, 'push');
            app.InsertImageButton.ButtonPushedFcn = createCallbackFcn(app, @InsertImageButtonPushed, true);
            app.InsertImageButton.Text = 'Insert  Image';
            app.BuiltinfunctionLabel = uilabel(app.TextureValuesPanel);
            app.BuiltinfunctionLabel.Text = 'Built in function';
            app.CalculatedLabel = uilabel(app.TextureValuesPanel);
            app.CalculatedLabel.Text = 'Calculated ';
            app.EnergyEditField_2Label = uilabel(app.TextureValuesPanel);
            app.EnergyEditField_2Label.Text = 'Energy';
            app.EnergyEditField_2 = uieditfield(app.TextureValuesPanel, 'numeric');
            app.ContrastEditField_2Label = uilabel(app.TextureValuesPanel);
            app.ContrastEditField_2Label.Text = 'Contrast';
            app.ContrastEditField_2 = uieditfield(app.TextureValuesPanel, 'numeric');
            app.HomogeneityEditField_2Label = uilabel(app.TextureValuesPanel);
            app.HomogeneityEditField_2Label.Text = 'Homogeneity';
            app.HomogeneityEditField_2 = uieditfield(app.TextureValuesPanel, 'numeric');
            app.CorrelationEditFieldLabel = uilabel(app.TextureValuesPanel);
            app.CorrelationEditFieldLabel.Text = 'Correlation';
            app.CorrelationEditField = uieditfield(app.TextureValuesPanel, 'numeric');
            app.IMAGETEXTUREFEATUREEXTRACTIONUSINGGLCMAPPROACHLabel = uilabel(app.UIFigure);
            app.IMAGETEXTUREFEATUREEXTRACTIONUSINGGLCMAPPROACHLabel.Text = 'IMAGE TEXTURE FEATURE EXTRACTION USING GLCM APPROACH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MINIPROJECT_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end